classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Panel       matlab.ui.container.Panel
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        Button      matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ((app.CheckBox.Value) && (app.CheckBox_2.Value==0))
                x = -2*pi:0.01:2*pi;
                plot(app.UIAxes, x, sin(x))
            end
            if ((app.CheckBox_2.Value) && (app.CheckBox.Value==0))
                x = -2*pi:0.01:2*pi;
                plot(app.UIAxes, x, cos(x))
            end
            if ((app.CheckBox.Value == 0) && (app.CheckBox_2.Value==0))
                plot(app.UIAxes, 0, 0)
            end
       
            if (app.CheckBox.Value && app.CheckBox_2.Value)
                x=0:0.01:4*pi;
                plot(app.UIAxes,x,sin(x),x,cos(x));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Группа переключателей';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = 'График синуса';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.Text = 'График косинуса';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычисление';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = {'-3'; '-2'; '-1'; '0'; '1'; '2'; '3'};
            app.UIAxes.YTickLabel = {'-10'; '-8'; '-6'; '-4'; '-2'; '0'; '2'; '4'; '6'; '8'; '10'};
            app.UIAxes.ZTick = [-3 1 3];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end