classdef prog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label_5           matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        Label_6           matlab.ui.control.Label
        EditField_3       matlab.ui.control.EditField
        Label_7           matlab.ui.control.Label
        EditField_4       matlab.ui.control.EditField
        EditField_5Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.EditField
        EditField_6Label  matlab.ui.control.Label
        EditField_6       matlab.ui.control.EditField
        Label_9           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Label_11          matlab.ui.control.Label
        EditField_11      matlab.ui.control.NumericEditField
        EditField_12      matlab.ui.control.NumericEditField
        EditField_13      matlab.ui.control.NumericEditField
        Label_12          matlab.ui.control.Label
        EditField_14      matlab.ui.control.NumericEditField
        Label_13          matlab.ui.control.Label
        Label_14          matlab.ui.control.Label
    end

    
    properties (Access = private)
        input1;
       input2;
      input3;
     input4; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            app.input1 = app.EditField_11.Value;
            
        end

        % Value changed function: EditField_12
        function EditField_12ValueChanged(app, event)
            app.input2 = app.EditField_12.Value;
            
        end

        % Value changed function: EditField_13
        function EditField_13ValueChanged(app, event)
            app.input3 = app.EditField_13.Value;
            
        end

        % Value changed function: EditField_14
        function EditField_14ValueChanged(app, event)
            app.input4 = app.EditField_14.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            sum = app.input1+app.input2+app.input3;
            app.Label_13.Text = sprintf('%d',sum);
            if (sum >= app.input4) 
                app.Label_14.Text = 'Поздравляем!!!';
            else 
                app.Label_14.Text = 'Вы не поступили.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия, Имя, Отчество';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предмет';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы  ЕГЭ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = '';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = '';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = 'Сумма баллов:';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Вычисление';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '';
            app.EditField_11 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_12 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_12.ValueChangedFcn = createCallbackFcn(app, @EditField_12ValueChanged, true);
            app.EditField_13 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_13.ValueChangedFcn = createCallbackFcn(app, @EditField_13ValueChanged, true);
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = 'Проходной балл';
            app.EditField_14 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_14.ValueChangedFcn = createCallbackFcn(app, @EditField_14ValueChanged, true);
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end